classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        SamplerDropDown             matlab.ui.control.DropDown
        SamplerDropDownLabel        matlab.ui.control.Label
        RLEditField                 matlab.ui.control.NumericEditField
        RLEditFieldLabel            matlab.ui.control.Label
        BDEditField                 matlab.ui.control.NumericEditField
        BDEditFieldLabel            matlab.ui.control.Label
        ERmEditField                matlab.ui.control.NumericEditField
        ERmLabel                    matlab.ui.control.Label
        CalculationparametersLabel  matlab.ui.control.Label
        GWTEditField                matlab.ui.control.NumericEditField
        GWTEditFieldLabel           matlab.ui.control.Label
        MwEditField                 matlab.ui.control.NumericEditField
        MwEditFieldLabel            matlab.ui.control.Label
        PGAEditField                matlab.ui.control.NumericEditField
        PGAEditFieldLabel           matlab.ui.control.Label
        GeneralparametersLabel      matlab.ui.control.Label
        MethodsButtonGroup          matlab.ui.container.ButtonGroup
        ProbabilisticCPTButton      matlab.ui.control.ToggleButton
        ProbabilisticSPTButton      matlab.ui.control.ToggleButton
        DeterministicCPTButton      matlab.ui.control.ToggleButton
        DeterministicSPTButton      matlab.ui.control.ToggleButton
        RightPanel                  matlab.ui.container.Panel
        CalculateButton             matlab.ui.control.Button
        RemovelayerButton           matlab.ui.control.Button
        AddlayerButton              matlab.ui.control.Button
        versionLabel                matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        RiskLiqLabel                matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        Id % Id of table row
        Method % Selected method
    end

    methods (Access = private)

        function setMethod(app)
            selectedButton = app.MethodsButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "Deterministic SPT"
                    app.Method = 1;
                case "Deterministic CPT"
                    app.Method = 2;
                case "Probabilistic SPT"
                    app.Method = 3;
                case "Probabilistic CPT"
                    app.Method = 4;
            end
        end

        function parameters = setGlobalParameters(app)
            setMethod(app)
            parameters.method = app.Method;
            parameters.pga = app.PGAEditField.Value;
            parameters.mw = app.MwEditField.Value;
            parameters.gwt = app.GWTEditField.Value;
            parameters.Erm = app.ERmEditField.Value;
            parameters.BD = app.BDEditField.Value;
            parameters.RL = app.RLEditField.Value;
            parameters.sampler = app.SamplerDropDown.ValueIndex;
        end

        function table = getDataInput(app)
            table = app.UITable.Data;
        end

        function [depth,Nm,FC,Gd,Gs] = getSoilData(~,row)
            depth= row(1);
            Nm= row(2);
            FC= row(3);
            Gd=row(4);
            Gs= row(5);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddlayerButton
        function AddlayerButtonPushed(app, event)
            data= app.UITable.Data;
            newRow= [0 0 0 0 0];
            newData= [data; newRow];
            app.UITable.Data= newData;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            try
                index = event.Indices(1);
                app.Id= index;
                app.RemovelayerButton.Enable= "on";
            catch e
                focus(app.UIFigure);
                app.RemovelayerButton.Enable = "off";
            end
        end

        % Button pushed function: RemovelayerButton
        function RemovelayerButtonPushed(app, event)
            newData = app.UITable.Data;
            newData(app.Id,:) = [];
            app.UITable.Data= newData;
            app.RemovelayerButton.Enable = "off";
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            parameters = setGlobalParameters(app);
            table = getDataInput(app);
            i=0;
            [depth,Nm,FC,Gd,Gs] = getSoilData(app,table(i,:));
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {170, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MethodsButtonGroup = uibuttongroup(app.LeftPanel);
            app.MethodsButtonGroup.Title = 'Methods';
            app.DeterministicSPTButton = uitogglebutton(app.MethodsButtonGroup);
            app.DeterministicSPTButton.Text = 'Deterministic SPT';
            app.DeterministicSPTButton.Value = true;
            app.DeterministicCPTButton = uitogglebutton(app.MethodsButtonGroup);
            app.DeterministicCPTButton.Text = 'Deterministic CPT';
            app.ProbabilisticSPTButton = uitogglebutton(app.MethodsButtonGroup);
            app.ProbabilisticSPTButton.Text = 'Probabilistic SPT';
            app.ProbabilisticCPTButton = uitogglebutton(app.MethodsButtonGroup);
            app.ProbabilisticCPTButton.Text = 'Probabilistic CPT';
            app.GeneralparametersLabel = uilabel(app.LeftPanel);
            app.GeneralparametersLabel.Text = 'General parameters';
            app.PGAEditFieldLabel = uilabel(app.LeftPanel);
            app.PGAEditFieldLabel.Text = 'P.G.A';
            app.PGAEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MwEditFieldLabel = uilabel(app.LeftPanel);
            app.MwEditFieldLabel.Text = 'Mw';
            app.MwEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GWTEditFieldLabel = uilabel(app.LeftPanel);
            app.GWTEditFieldLabel.Text = 'G.W.T';
            app.GWTEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CalculationparametersLabel = uilabel(app.LeftPanel);
            app.CalculationparametersLabel.Text = 'Calculation parameters';
            app.ERmLabel = uilabel(app.LeftPanel);
            app.ERmLabel.Text = 'ERm';
            app.ERmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BDEditFieldLabel = uilabel(app.LeftPanel);
            app.BDEditFieldLabel.Text = 'BD';
            app.BDEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RLEditFieldLabel = uilabel(app.LeftPanel);
            app.RLEditFieldLabel.Text = 'RL';
            app.RLEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplerDropDownLabel = uilabel(app.LeftPanel);
            app.SamplerDropDownLabel.Text = 'Sampler';
            app.SamplerDropDown = uidropdown(app.LeftPanel);
            app.SamplerDropDown.Items = {'Standard', 'w/o Liners'};
            app.SamplerDropDown.Value = 'Standard';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Depth (m)'; 'Nm'; 'F.C'; 'G dry'; 'G sat'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.RiskLiqLabel = uilabel(app.RightPanel);
            app.RiskLiqLabel.Text = 'RiskLiq';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, '70_Rojo.jpg');
            app.versionLabel = uilabel(app.RightPanel);
            app.versionLabel.Text = 'v1.0';
            app.AddlayerButton = uibutton(app.RightPanel, 'push');
            app.AddlayerButton.ButtonPushedFcn = createCallbackFcn(app, @AddlayerButtonPushed, true);
            app.AddlayerButton.Text = 'Add layer';
            app.RemovelayerButton = uibutton(app.RightPanel, 'push');
            app.RemovelayerButton.ButtonPushedFcn = createCallbackFcn(app, @RemovelayerButtonPushed, true);
            app.RemovelayerButton.Enable = 'off';
            app.RemovelayerButton.Text = 'Remove layer';
            app.CalculateButton = uibutton(app.RightPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end